classdef FinalASEPTCC6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % Ferram...
        TabGroup                matlab.ui.container.TabGroup       % Estabi...
        Tab                     matlab.ui.container.Tab            % Estabi...
        Button                  matlab.ui.control.Button           % Calcular
        Button2                 matlab.ui.control.Button           % Limpar
        Button3                 matlab.ui.control.Button           % LINHAR...
        Gerador                 matlab.ui.container.Panel          % Dados ...
        Label6                  matlab.ui.control.Label            % Consta...
        H1                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField10 matlab.ui.control.Label            % Tensão...
        Vgeracao1               matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField11 matlab.ui.control.Label            % Potênc...
        Sgeracao1               matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField12 matlab.ui.control.Label            % Reatân...
        Xdgeracao1              matlab.ui.control.NumericEditField % [-Inf ...
        Label27                 matlab.ui.control.Label            % Número...
        numgeradores1           matlab.ui.control.NumericEditField % [-Inf ...
        LinhaGeracao            matlab.ui.container.Panel          % Dados ...
        LabelNumericEditField13 matlab.ui.control.Label            % Tensão...
        VLtgeracao1             matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField14 matlab.ui.control.Label            % Potênc...
        SLtgeracao1             matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField15 matlab.ui.control.Label            % Reatân...
        XLtgeracao1             matlab.ui.control.NumericEditField % [-Inf ...
        Linhadetransmissao      matlab.ui.container.Panel          % Linhas...
        LabelNumericEditField4  matlab.ui.control.Label            % Potênc...
        SLt11                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField16 matlab.ui.control.Label            % Reatân...
        XLt11                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField6  matlab.ui.control.Label            % Reatân...
        XLt21                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField17 matlab.ui.control.Label            % Linha ...
        LTD1                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField18 matlab.ui.control.Label            % Tensão...
        VLt11                   matlab.ui.control.NumericEditField % [-Inf ...
        Label23                 matlab.ui.control.Label            % Porcen...
        queda1                  matlab.ui.control.NumericEditField % [-Inf ...
        Parametrosbarras        matlab.ui.container.Panel          % Parâme...
        LabelNumericEditField   matlab.ui.control.Label            % Tensão...
        VT1                     matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Tensão...
        VA1                     matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % Ângulo...
        angleVA1                matlab.ui.control.NumericEditField % [-Inf ...
        ParametrosSist          matlab.ui.container.Panel          % Parâme...
        LabelNumericEditField3  matlab.ui.control.Label            % Potênc...
        Pe1                     matlab.ui.control.NumericEditField % [-Inf ...
        Label4                  matlab.ui.control.Label            % Potênc...
        Pmec1                   matlab.ui.control.NumericEditField % [-Inf ...
        Label5                  matlab.ui.control.Label            % Frequê...
        fe1                     matlab.ui.control.NumericEditField % [-Inf ...
        Label17                 matlab.ui.control.Label            % Estabi...
        Panel2                  matlab.ui.container.Panel          % Dados ...
        Label19                 matlab.ui.control.Label            % Impedâ...
        impedanciatrafo1        matlab.ui.control.NumericEditField % [-Inf ...
        Label20                 matlab.ui.control.Label            % Tensão...
        Vprimario1              matlab.ui.control.NumericEditField % [-Inf ...
        Label21                 matlab.ui.control.Label            % Potênc...
        Stransformador1         matlab.ui.control.NumericEditField % [-Inf ...
        Label22                 matlab.ui.control.Label            % Tensão...
        Vsecundario1            matlab.ui.control.NumericEditField % [-Inf ...
        Label28                 matlab.ui.control.Label            % Número...
        numerotrafos1           matlab.ui.control.NumericEditField % [-Inf ...
        BasesSitema             matlab.ui.container.Panel          % Bases ...
        LabelNumericEditField9  matlab.ui.control.Label            % Potênc...
        SpuSist1                matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField19 matlab.ui.control.Label            % Tempo ...
        Tempoexec2              matlab.ui.control.NumericEditField % [-Inf ...
        Label26                 matlab.ui.control.Label            % segundos
        Tab2                    matlab.ui.container.Tab            % Fluxo ...
        Label10                 matlab.ui.control.Label            % Inseri...
        Label11                 matlab.ui.control.Label            % Inseri...
        Label12                 matlab.ui.control.Label            % Result...
        Label13                 matlab.ui.control.Label            % Ferram...
        Label15                 matlab.ui.control.Label            % segundos
        Barras2                 matlab.ui.control.Button           % Parâme...
        Button9                 matlab.ui.control.Button           % Dados ...
        Button10                matlab.ui.control.Button           % Calcular
        LabelNumericEditField20 matlab.ui.control.Label            % Tempo ...
        Tempoexec               matlab.ui.control.NumericEditField % [-Inf ...
        Tab3                    matlab.ui.container.Tab            % Result...
        EQpotenciaangulo        matlab.ui.container.Panel          % Equaçõ...
        RESULTADOS1             matlab.ui.control.EditField       
        RESULTADOS4             matlab.ui.control.EditField       
        RESULTADOS6             matlab.ui.control.EditField       
        TensoesComplexas        matlab.ui.container.Panel          % Tensõe...
        RESULTADOS2             matlab.ui.control.EditField       
        RESULTADOS3             matlab.ui.control.EditField       
        PeZero                  matlab.ui.container.Panel          % Ângulo...
        RESULTADOS5             matlab.ui.control.EditField       
        RESULTADOS7             matlab.ui.control.EditField       
        EqOscilacao             matlab.ui.container.Panel          % Equaçõ...
        OS1                     matlab.ui.control.EditField       
        OS2                     matlab.ui.control.EditField       
        OS3                     matlab.ui.control.EditField       
        PEdifZero               matlab.ui.container.Panel          % Ângulo...
        RESULTADOS9             matlab.ui.control.EditField       
        RESULTADOS8             matlab.ui.control.EditField       
        Panel                   matlab.ui.container.Panel         
        UIAxes                  matlab.ui.control.UIAxes           % Potênc...
        Label24                 matlab.ui.control.Label            % Result...
        Button12                matlab.ui.control.Button           % Abrir
        Label25                 matlab.ui.control.Label            % Exibir...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Barras2 button pushed function
        function BotaoBarras(app)
            winopen('Barras.xlsx')
        end

        % Button9 button pushed function
        function BotaoLinhas(app)
            winopen('Linhas.xlsx')
        end

        % Button10 button pushed function
        function Calcular(app)
            tic
            [Barras] = xlsread('Barras.xlsx');
            [Linhas] = xlsread('Linhas.xlsx');
            %Tamanho das Matrizes
            [numeroBARRAS,~] = size(Barras);
            [numeroCONEXOES,~]  = size(Linhas);
            
            %Valor do erro das iterações
            erro = 0.001;
            
            %Obtendo dados das Matrizes
            barras1 = 0;
            barras2 = 0;
            barras3 = 0;
            barras1V = 0;
            
            tipobarra = zeros(1,numeroBARRAS);
            theta = zeros(1,numeroBARRAS);
            V = zeros(1,numeroBARRAS);
            nomebarra = zeros(1,numeroBARRAS);
            Pativa = zeros(numeroBARRAS,1);
            Qreati = zeros(numeroBARRAS,1);
            thetaini = zeros(numeroBARRAS,1);
            tensaoini = zeros(numeroBARRAS,1);
            for cont1=1:numeroBARRAS
                tipobarra(cont1) = Barras(cont1,6);
                theta(cont1) = Barras(cont1,3);
                V(cont1) = Barras(cont1,2);
                nomebarra(cont1) = Barras(cont1,1);
                if Barras(cont1,6) == 3
                    barras3 = barras3 + 1;
                    Pativa(cont1,1) = Barras(cont1,4);
                    Qreati(cont1,1) = Barras(cont1,5);
                    thetaini(cont1,1) = Barras(cont1,3);
                    tensaoini(cont1,1) = Barras(cont1,2);
                end
                if Barras(cont1,6) == 2
                    barras2 = barras2 + 1;
                    Pativa(cont1,1) = Barras(cont1,4);
                    thetaini(cont1,1) = Barras(cont1,3);
                end
                if Barras(cont1,6) == 1
                    barras1 = barras1 + 1;
                    barras1V = barras1V + 1;
                    
                end
            end
            BarraORIGEM = zeros(1,numeroBARRAS);
            BarraDESTINO = zeros(1,numeroBARRAS);
            resistencia = zeros(1,numeroBARRAS);
            reatancia = zeros(1,numeroBARRAS);
            for cont2=1:numeroCONEXOES
                BarraORIGEM(cont2) = Linhas(cont2,1); %captura o valor da barra de origem da matriz Linha
                BarraDESTINO(cont2) = Linhas(cont2,2); %captura o valor da barra de destino da matriz linha
                BarraORIGEM2(cont2) = Linhas(cont2,1); %captura o valor da barra de origem da matriz Linha
                BarraDESTINO2(cont2) = Linhas(cont2,2); %captura o valor da barra de destino da matriz linha
                resistencia(cont2) = Linhas(cont2,3); %captura o valor da resistencia
                reatancia(cont2) = Linhas(cont2,4);% captura o valor da reatancia
            end
            
            for xi=1:numeroBARRAS
                for xj=1:numeroCONEXOES
                    if nomebarra(xi) == BarraORIGEM(xj)
                        BarraORIGEM(xi) = xi;
                    end
                end
            end
            
            for xi=1:numeroCONEXOES
                for xj=1:numeroBARRAS
                    if BarraDESTINO(xi) == nomebarra(xj)
                        BarraDESTINO(xi) = xj;
                    end
                end
            end
            
            %Montagem da matriz ZBARRA
            Zbarra = zeros(numeroCONEXOES,numeroCONEXOES);
            for cont3=1:numeroCONEXOES
                k = BarraORIGEM(cont3);
                m = BarraDESTINO(cont3);
                Zbarra(k,m) = resistencia(cont3)+ 1i*reatancia(cont3);
                Zbarra(m,k) = Zbarra(k,m);
            end
            
            %Montagem da matriz YBARRA
            %Montagem dos Elementos YBARRA
            Ybarra = zeros(numeroBARRAS,numeroBARRAS);
            for I=1:numeroBARRAS
                for j=1:numeroBARRAS
                    if Zbarra(I,j) ~= 0 ;
                        Ybarra(I,j) = -1/Zbarra(I,j);
                    else
                        Ybarra(I,j) = 0;
                    end
                end
            end
            
            %Montagem da diagonal YBARRA
            for cont4=1:numeroBARRAS
                for cont5=1:numeroBARRAS
                    if cont4 == cont5;
                        Ybarra(cont4,cont5) = -1*sum(Ybarra(cont4,:));
                    end
                end
            end
            Filename='Resultados.xlsx';
            [~,~, Raw]=xlsread(Filename,2);
            [Raw{:, :}]=deal(NaN);
            xlswrite(Filename, Raw, 2,'A6:N21');
            YbarraExcel = num2cell(Ybarra);
            YbarraExcel = cellfun(@num2str , YbarraExcel, 'UniformOutput', false);
            xlswrite('Resultados.xlsx',YbarraExcel',2,'A7')
            %Obter Matriz G e B
            G = real(Ybarra);
            B = imag(Ybarra);
            
            %%%MATRIZPQ ESPECIFICADO
            %ESPECIFICADO P
            barras1 = barras1+1;
            barras4 = barras1+barras2;
            Pativa = Pativa(barras1:end,:);
            [sizeESPP,~] = size(Pativa);
            TamESPP = barras2+2*barras3; % numero linhas deltapq
            SupM_ESPP = zeros((TamESPP-sizeESPP),1);
            Pativa = [Pativa;SupM_ESPP];
            %ESPECIFICADO Q
            [TamESPQ,~] = size(Qreati);
            SupM_ESPQ = zeros((barras2+2*barras3),1);
            [TamSUPESPQ,~] = size(SupM_ESPQ);
            SupM_ESPQ2 = zeros((TamSUPESPQ-TamESPQ),1);
            Qreati = [SupM_ESPQ2;Qreati];
            
            %MATRIZ PQ ESPECIFICADO:
            PQ_ESPECIFICADO = Pativa+Qreati;
            
            %Acertando o tamanho da matriz THETA INICIAL
            thetaini = thetaini(barras1:end,:);
            linhaDeltaPQ = barras2+2*barras3;
            [linhathetaini,~] = size(thetaini);
            Matrizsuporte = zeros((linhaDeltaPQ-linhathetaini),1);
            thetaini = [thetaini;Matrizsuporte];
            
            %Acertando o tamanho da matriz TENSAO INICIAL
            tensaoini = tensaoini(barras1:end,:);
            linhaDeltaPQ = barras2+2*barras3;
            [linhatensaoini,~] = size(tensaoini);
            Matrizsuporte2 = zeros((linhaDeltaPQ-linhatensaoini),1);
            tensaoini = [Matrizsuporte2;tensaoini] ;
            
            ThetaV_ini = thetaini+tensaoini;
            
            ite =0;
            ValorERRO = 1;
            while ValorERRO > erro
                %Calcula Matriz de Potência ativa
                MatrizPATIVA = zeros(numeroBARRAS,1);
                P = zeros(numeroBARRAS,1);
                for k=1:numeroBARRAS
                    if tipobarra(1,k) ~= 1
                        for m=1:numeroBARRAS
                            P(k,1) = V(1,k)*(V(1,m)*(G(k,m)*cos(theta(k)-theta(m)) + B(k,m)*sin(theta(k)-theta(m))));
                            MatrizPATIVA(k,1) = MatrizPATIVA(k,1)+ P(k,1);
                        end
                    end
                end
                
                %Calcula Matriz de Potência reativa
                if barras3~=0
                    Q = zeros(numeroBARRAS,1);
                    MatrizPREATIVA = zeros(numeroBARRAS,1);
                    for k=1:numeroBARRAS
                        if tipobarra(1,k) == 3
                            for m=1:numeroBARRAS
                                Q(k,1) = V(1,k)*(V(1,m)*(G(k,m)*sin(theta(k)-theta(m)) - B(k,m)*cos(theta(k)-theta(m))));
                                MatrizPREATIVA(k,1) = MatrizPREATIVA(k,1)+ Q(k,1);
                            end
                        end
                    end
                end
                
                %%%MATRIZPQ CALCULADO
                %CALCULADO P
                MatrizPATIVA = MatrizPATIVA(barras1:end,:);
                [sizeCALCP,~] = size(MatrizPATIVA);
                TamCALCP = barras2+2*barras3; % numero linhas deltapq
                SupM_CALCP = zeros((TamCALCP-sizeCALCP),1);
                MatrizPATIVA = [MatrizPATIVA;SupM_CALCP];
                if barras3 ~=0
                    %CALCULADO Q
                    %MatrizPREATIVA;
                    [TamCALCQ,~] = size(MatrizPREATIVA);
                    SupM_CALCQ = zeros((barras2+2*barras3),1);
                    [TamSUPCALCQ,~] = size(SupM_CALCQ);
                    SupM_CALCQ2 = zeros((TamSUPCALCQ-TamCALCQ),1);
                    MatrizPREATIVA = [SupM_CALCQ2;MatrizPREATIVA];
                end
                if barras3~=0
                    %MATRIZ PQ ESPECIFICADO:
                    PQ_CALCULADO = MatrizPATIVA+MatrizPREATIVA;
                else
                    PQ_CALCULADO = MatrizPATIVA;
                end
                
                %%%MATRIZ DELTAPQ
                DeltaPQ = PQ_ESPECIFICADO - PQ_CALCULADO;
                
                %Estruturar a matriz jacobiana
                %Tamanho das Matrizes que compoem a Jacobiana
                H = zeros(numeroBARRAS-1,numeroBARRAS-1);
                [~,~] = size(H);
                
                if barras3 ~= 0
                    M = zeros(barras3,numeroBARRAS-1);
                    N = zeros(numeroBARRAS-1,barras3);
                    L = zeros(barras3,barras3);
                end
                
                %CALCULO MATRIZ H
                %Calculo Diagonal H
                MatrizHDiagonal = zeros(numeroBARRAS,1);
                for k=1:numeroBARRAS
                    if tipobarra(1,k) ~= 1
                        for m=1:numeroBARRAS
                            MatrizHDiagonal(k,1) = MatrizHDiagonal(k,1)+ V(1,m)*(G(k,m)*sin(theta(k)-theta(m)) - B(k,m)*cos(theta(k)-theta(m)));
                        end
                        MatrizHDiagonal(k,1) =(-1)*V(1,k)*V(1,k)*B(k,k)- V(1,k)*MatrizHDiagonal(k,1);
                    end
                end
                MatrizHDiagonal = MatrizHDiagonal(barras1:end,:);
                Hprincipal = diag(MatrizHDiagonal);
                
                %Calculo Elementos H
                dpk2_dtm = zeros(numeroBARRAS,numeroBARRAS);
                for testesteste=1:numeroCONEXOES
                    ka  = BarraORIGEM(1,testesteste);
                    eme = BarraDESTINO(1,testesteste);
                    dpk2_dtm(ka,eme) = V(1,ka)*V(1,eme)*(G(ka,eme)*sin(theta(ka)-theta(eme))- B(ka,eme)*cos(theta(ka)-theta(eme)));
                    dpk2_dtm(eme,ka) =(V(1,ka)*V(1,eme)*(-G(ka,eme)*sin(theta(ka)-theta(eme))-B(ka,eme)*cos(theta(ka)-theta(eme))));
                end
                
                dpk2_dtm = dpk2_dtm(barras1:end,barras1:end);
                
                %Formar Matriz H
                H =  Hprincipal + dpk2_dtm;
                
                %CALCULO MATRIZ N
                %Calculo DIAGONAL N
                if barras3 ~=0;
                    Diagonal_N = zeros(numeroBARRAS,1);
                    Pt1 = zeros(numeroBARRAS,1);
                    
                    for k=1:numeroBARRAS
                        if tipobarra(1,k) ~= 1
                            Pt1(k,1) = V(1,k)*G(k,k);
                            for m=1:numeroBARRAS
                                Diagonal_N(k,1) = Diagonal_N(k,1)+ V(1,m)*(G(k,m)*cos(theta(k)-theta(m)) - B(k,m)*sin(theta(k)-theta(m)));
                            end
                            Diagonal_N(k,1) = Diagonal_N(k,1) + Pt1(k,1);
                        end
                    end
                    Diagonal_N = Diagonal_N(barras4:end,:);
                    Nprincipal = diag(Diagonal_N);
                    
                    % Calculo Elementos N
                    Elementos_N = zeros(numeroBARRAS,numeroBARRAS);
                    for testesteste=1:numeroCONEXOES
                        ka  = BarraORIGEM(1,testesteste);
                        eme = BarraDESTINO(1,testesteste);
                        Elementos_N(ka,eme) = V(1,ka)*(G(ka,eme)*cos(theta(ka)-theta(eme))+ B(ka,eme)*sin(theta(ka)-theta(eme)));
                        Elementos_N(eme,ka) = V(1,eme)*(G(ka,eme)*cos(theta(ka)-theta(eme))+ B(ka,eme)*sin(theta(ka)-theta(eme)));
                    end
                    Elementos_N = Elementos_N(barras1:end,barras4:end);
                    
                    %Formar Matriz N
                    [LinhaN,~] = size(Nprincipal);
                    DimensaoMatrizN = zeros(numeroBARRAS-LinhaN-1,barras3);
                    Nprincipal = [DimensaoMatrizN;Nprincipal];
                    if barras1V == 4
                        [LinhaNN,~] = size(Nprincipal);
                        [LinhaElementos,~] = size(Elementos_N);
                        Addlinhas = LinhaNN-LinhaElementos;
                        MatrizsupN = zeros(Addlinhas,barras3);
                        Elementos_N = [MatrizsupN;Elementos_N];
                    end
                    N = Nprincipal + Elementos_N;
                end
                
                %CALCULO MATRIZ L
                %Calculo DIAGONAL L
                if barras3~=0 % Para existir Matriz L, precisa ter ao menos 1 barra3 PQ
                    Diagonal_L = zeros(numeroBARRAS,1);
                    PtL = zeros(numeroBARRAS,1);
                    for k=1:numeroBARRAS
                        if tipobarra(1,k) ~= 1
                            PtL(k,1) = -V(1,k)*B(k,k);
                            for m=1:numeroBARRAS
                                Diagonal_L(k,1) = Diagonal_L(k,1)+ V(1,m)*(G(k,m)*sin(theta(k)-theta(m)) - B(k,m)*cos(theta(k)-theta(m)));
                            end
                            Diagonal_L(k,1) = Diagonal_L(k,1) + PtL(k,1);
                        end
                    end
                    Diagonal_L = Diagonal_L(barras4:end,:);
                    Lprincipal = diag(Diagonal_L);
                    
                    % Calculo Elementos L
                    Elementos_L = zeros(numeroBARRAS,numeroBARRAS);
                    for testesteste=1:numeroCONEXOES
                        ka  = BarraORIGEM(1,testesteste);
                        eme = BarraDESTINO(1,testesteste);
                        Elementos_L(ka,eme) = V(1,ka)*(G(ka,eme)*sin(theta(ka)-theta(eme))-B(ka,eme)*cos(theta(ka)-theta(eme)));
                        Elementos_L(eme,ka) = V(1,eme)*(G(ka,eme)*sin(theta(ka)-theta(eme))-B(ka,eme)*cos(theta(ka)-theta(eme)));
                        
                    end
                    Elementos_L = Elementos_L(barras4:end,barras4:end);
                    %Formar Matriz L
                    L = Lprincipal + Elementos_L;
                end
                
                %CALCULO MATRIZ M
                %Calculo Diagonal M
                if barras3~=0 % Para existir Matriz M, precisa ter ao menos 1 barra3 PQ
                    Diagonal_M = zeros(numeroBARRAS,1);
                    for k=1:numeroBARRAS
                        if tipobarra(1,k) ~= 1
                            for m=1:numeroBARRAS
                                Diagonal_M(k,1) = Diagonal_M(k,1)+ V(1,m)*(G(k,m)*cos(theta(k)-theta(m)) + B(k,m)*sin(theta(k)-theta(m)));
                            end
                            Diagonal_M(k,1) =(-1)*V(1,k)*V(1,k)*G(k,k)+ V(1,k)*Diagonal_M(k,1);
                        end
                    end
                    Diagonal_M = Diagonal_M(barras4:end,:);
                    Diagonal_M = diag(Diagonal_M);
                    
                    % Calculo Elementos M
                    Elementos_M = zeros(numeroBARRAS,numeroBARRAS);
                    for testesteste=1:numeroCONEXOES
                        ka  = BarraORIGEM(1,testesteste);
                        eme = BarraDESTINO(1,testesteste);
                        Elementos_M(ka,eme) = -V(1,ka)*V(1,eme)*(G(ka,eme)*cos(theta(ka)-theta(eme))+B(ka,eme)*sin(theta(ka)-theta(eme)));
                        Elementos_M(eme,ka) = -V(1,ka)*V(1,eme)*(G(ka,eme)*cos(theta(ka)-theta(eme))-B(ka,eme)*sin(theta(ka)-theta(eme)));
                    end
                    Elementos_M = Elementos_M(barras4:end,barras1:end);
                    
                    %Formar Matriz M
                    [~,ColunasN] = size(Elementos_M); % pegar o tamanho da matriz elementosM
                    [~,ColunadN] = size(Diagonal_M); % pegar o tamanho da matriz defasada em colunas
                    DimensaoMatrizM = zeros(barras3,ColunasN-ColunadN); % formar matriz com as linhas que faltam na diagonalm
                    Mprincipal = [DimensaoMatrizM Diagonal_M];
                    M = Mprincipal + Elementos_M;
                end
                
                %                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% JACOBIANA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %                  %%%%                                                                  %%%%
                %                  %%%%                        [    H      N    ]                        %%%%
                %                  %%%%                        [    M      L    ]                        %%%%
                %                  %%%%                                                                  %%%%
                %                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                if barras3 ==0
                    J = H;
                end
                if barras3 ~= 0
                    if barras1V == 4
                        N = N(barras1-1:end,:);
                    end
                    J = [ H N ; M L ];
                end
                
                %Calculando Delta Theta,tensao
                DeltaTV = J\DeltaPQ;
                
                %     %calculo ERRRO
                ThetaV_ini = ThetaV_ini + DeltaTV;
                
                %Separar ThetaV_ini em 1 matriz Angulo e outra Tensao
                %Isolando Matriz Angulos
                Mang = flipud(ThetaV_ini);
                Mang = Mang((barras3+1:end),1);
                Mang = flipud(Mang);
                %Tornando matriz angulos do tamanho do sistema
                [tamSist,~] = size(barras1V);
                Sup_Mang = zeros((tamSist),1);
                Mang = [Sup_Mang;Mang];
                Mang = Mang';
                if barras1V ==4
                    supmang = [ 0 0 0 ];
                    Mang = [supmang Mang];
                end
                %Isolando Matriz Tensoes
                Mten = ThetaV_ini((barras2+barras3+1:end),1);
                Sup_Mten = zeros((barras1V+barras2),1);
                Mten = [Sup_Mten;Mten];
                Mten = Mten';
                
                %%Novos valores Angulos e Tensoes
                %%Novos Angulos
                for k=1:numeroBARRAS
                    if tipobarra(k) ~=1
                        theta(1,k) = Mang(1,k);
                    end
                end
                %%Novas Tensoes
                for k=1:numeroBARRAS
                    if tipobarra(k) == 3
                        V(1,k) = Mten(1,k);
                    end
                end
                
                
                ModuloDeltaTV = abs(DeltaTV);
                ValorERRO = max(ModuloDeltaTV);
                ite = ite+1;
            end
            
            %POTENCIA ATIVA EM CADA BARRA APOS ITERAÇÕES
            PAtiva_final = zeros(numeroBARRAS,1);
            Pf = zeros(numeroBARRAS,1);
            for k=1:numeroBARRAS
                for m=1:numeroBARRAS
                    Pf(k,1) = V(1,k)*(V(1,m)*(G(k,m)*cos(theta(k)-theta(m)) + B(k,m)*sin(theta(k)-theta(m))));
                    PAtiva_final(k,1) = PAtiva_final(k,1)+ Pf(k,1);
                end
            end
            %POTENCIA REATIVA EM CADA BARRA APOS ITERAÇÕES
            Qf = zeros(numeroBARRAS,1);
            % if barras3~=0
            PReativa_final = zeros(numeroBARRAS,1);
            for k=1:numeroBARRAS
                for m=1:numeroBARRAS
                    Qf(k,1) = V(1,k)*(V(1,m)*(G(k,m)*sin(theta(k)-theta(m)) - B(k,m)*cos(theta(k)-theta(m))));
                    PReativa_final(k,1) = PReativa_final(k,1)+ Qf(k,1);
                end
            end
            %end
            %POTENCIA ATIVA ENTRE AS BARRAS
            P_entrebarras = zeros(numeroCONEXOES,1);
            Q_entrebarras = zeros(numeroCONEXOES,1);
            Pperdas= zeros(numeroCONEXOES,1);
            Qperdas= zeros(numeroCONEXOES,1);
            for btw=1:numeroCONEXOES
                K = BarraORIGEM(1,btw);
                M = BarraDESTINO(1,btw);
                P_entrebarras(btw,1) =  G(K,M)*V(1,K)^2 - G(K,M)*V(1,K)*V(1,M)*cos(theta(K)-theta(M))-B(K,M)*V(1,K)*V(1,M)*sin(theta(K)-theta(M));
                Q_entrebarras(btw,1) = -B(K,M)*V(1,K)^2 - G(K,M)*V(1,K)*V(1,M)*sin(theta(K)-theta(M))+B(K,M)*V(1,K)*V(1,M)*cos(theta(K)-theta(M));
                Pperdas(btw,1) =   G(K,M)*(V(1,K)^2 + V(1,M)^2)-2*G(K,M)*V(1,K)*V(1,M)*cos(theta(K)-theta(M));
                Qperdas(btw,1) =  -B(K,M)*(V(1,K)^2 + V(1,M)^2)+2*B(K,M)*V(1,K)*V(1,M)*cos(theta(K)-theta(M));
            end

            
            %excel
            Filename='Resultados.xlsx';
            [N, T, Raw]=xlsread(Filename,1);
            [Raw{:, :}]=deal(NaN);
            xlswrite(Filename, Raw, 1,'A8:M30');
            xlswrite('Resultados.xlsx',nomebarra',1,'A8')
            xlswrite('Resultados.xlsx',tipobarra',1,'F8')
            xlswrite('Resultados.xlsx',V',1,'B8')
            xlswrite('Resultados.xlsx',(theta*180/pi)',1,'C8')
            xlswrite('Resultados.xlsx',PAtiva_final,1,'D8')
            xlswrite('Resultados.xlsx',PReativa_final,1,'E8')
            xlswrite('Resultados.xlsx',BarraORIGEM2',1,'H8')
            xlswrite('Resultados.xlsx',BarraDESTINO2',1,'I8')
            xlswrite('Resultados.xlsx',P_entrebarras,1,'J8')
            xlswrite('Resultados.xlsx',Q_entrebarras,1,'K8')
            xlswrite('Resultados.xlsx',Qperdas,1,'L8')
            xlswrite('Resultados.xlsx',Pperdas,1,'M8')
            xlswrite('Resultados.xlsx',ite,1,'O1')
            xlswrite('Resultados.xlsx',ValorERRO,1,'O2')
            fprintf(' Fim de execução \n')
            tempo = toc;
            app.Tempoexec.Value = tempo;
            winopen('Resultados.xlsx')
        end

        % Button3 button pushed function
        function Testehum(app)
           app.Vprimario1.Value = 13.8;
            app.Vsecundario1.Value =138;
            app.Stransformador1.Value = 82;
            app.impedanciatrafo1.Value = 13;
            app.numerotrafos1.Value = 2;
            app.queda1.Value = 50;
            app.SpuSist1.Value =  253.176;
            app.Vgeracao1.Value = 13.8;
            app.Xdgeracao1.Value =32.6;
            app.Sgeracao1.Value =253.176;
            app.VLtgeracao1.Value=0;
            app.XLtgeracao1.Value =0;
            app.SLtgeracao1.Value =0;
            app.VLt11.Value = 138;
            app.XLt11.Value =13.48;
            app.SLt11.Value = 100;
            app.XLt21.Value =13.48;
            app.LTD1.Value = 2;
            app.VT1.Value = 1;
            app.VA1.Value = 1;
            app.Pe1.Value = 1;
            app.angleVA1.Value =0;
            app.fe1.Value = 60;
            app.Pmec1.Value = 1;
            app.H1.Value = 5;
            app.numgeradores1.Value = 1;
            
            
        end

        % Button button pushed function
        function CalcularEstabilidade(app)
            %%%%%%%%% entrada de dados
            tic
            SpuSist = app.SpuSist1.Value;
            Vgeracao = app.Vgeracao1.Value;
            Xdgeracao = app.Xdgeracao1.Value;
            Sgeracao = app.Sgeracao1.Value;
            VLtgeracao = app.VLtgeracao1.Value;
            XLtgeracao = app.XLtgeracao1.Value;
            SLtgeracao = app.SLtgeracao1.Value;
            VLt1 = app.VLt11.Value;
            XLt1 = app.XLt11.Value;
            SLt1 = app.SLt11.Value;
            XLt2 = app.XLt21.Value;
            LTD = app.LTD1.Value;
            Vprimario = app.Vprimario1.Value;
            Vsecundario = app.Vsecundario1.Value;
            Strafo = app.Stransformador1.Value;
            Xtrafo = app.impedanciatrafo1.Value;
            queda = app.queda1.Value;
            numgeradores = app.numgeradores1.Value;
            numerotrafos = app.numerotrafos1.Value;
            
            %tirar de porcentagem
            Xdgeracao = Xdgeracao/100;
            XLtgeracao = XLtgeracao/100;
            XLt1 = XLt1/100;
            XLt2 = XLt2/100;
            Xtrafo = Xtrafo/100;
            
                
            %Condições
            VpuSist = Vgeracao;
            Potele = Sgeracao/SpuSist;
            Pomec = (Sgeracao/SpuSist);
            %Geração
            if numgeradores>1
                Xd = 1/(numgeradores*(1/Xdgeracao));
            else
                Xd = Xdgeracao;
            end
            Xd = Xd*(Vgeracao/VpuSist)^2 * SpuSist/Sgeracao;
            %Linha Geração
            if VLtgeracao == 0 || XLtgeracao == 0 || SLtgeracao == 0
                XLtgeracaoNew = 0;
            else
                XLtgeracaoNew = XLtgeracao*(VLtgeracao/VpuSist)^2 * SpuSist/SLtgeracao;
            end
            
            %Trafo
            if numerotrafos>1
                Xt1 = 1/(numerotrafos*(1/Xtrafo));
            else
                Xt1 = Xtrafo;
            end
            Xt1 = Xt1*(Vprimario/VpuSist)^2 * SpuSist/Strafo;

            %Linha 1
            VpuSist2 = VpuSist*(Vsecundario/Vprimario);
            XLt1New = XLt1*(VLt1/VpuSist2)^2 * SpuSist/SLt1;
            
            %Linha 2
            XLt2New = XLt2*(VLt1/VpuSist2)^2 * SpuSist/SLt1;
            
            %XtaRP
            XtaRP = (XLt1New*XLt2New)/(XLt1New+XLt2New) + Xt1;
            
            %XeaPF
            if(LTD == 1)
                XeaPF = Xd + XLtgeracaoNew + Xt1 + XLt2New;
            else if (LTD == 2)
                    XeaPF = Xd + XLtgeracaoNew + Xt1 + XLt1New;
                end
            end
            
            %formar matri Zbarra
            Z11 = 0;
            Z21 = 0;
            Z31 = Xd + XLtgeracaoNew + Xt1;
            Z12 = 0;
            if(LTD == 1)
                Z22 = XLt1New*(100-queda)/100;
                Z32 = XLt2New;
                Z33 = XLt1New- Z22;
                Z23 = XLt2New;
            else if (LTD == 2)
                    Z22 = XLt2New*(100-queda)/100;
                    Z32 = XLt1New;
                    Z33 = XLt2New- Z22;
                    Z23 = XLt1New;
                end
            end
            Z13 = Z31;
            Zbarra = [ Z11 Z12 Z13 ; Z21 Z22 Z23 ; Z31 Z32 Z33 ]*1i;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%REGIME PERMANENTE%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Declarando variáveis
            cla(app.UIAxes);
            
            VT = app.VT1.Value;
            VA = app.VA1.Value;
            PE = app.Pe1.Value;
            angleVA = app.angleVA1.Value;
            
            PE = PE*(Potele);
            % Equações e lógica
            M = ((PE*XtaRP)/(VT*VA));
            alphaRAD = asin(M);
            alpha = alphaRAD*180/pi;
            
            %Cálculo da corrente
            [Vtr,Vti] = pol2cart(alphaRAD,VT); %obtendo VT complexo em Cart
            Vt1 = Vtr + Vti*1i;                %VT em cartesiano
            [Var,Vai] = pol2cart(angleVA,VA);  %Obtendo VA complexo em Cart
            Va1 = Var + Vai;                   %VA em cartesiano
            XtaRP1 = XtaRP*1i;                 %XtaRP complexo em Cart
            
            I3 = (Vt1-Va1)./(XtaRP1);          %Valor da corrente em Cart/rad
            %Imodule = abs(I3);                %Módulo da corrente em polar
            %Iangle = angle(I3)*180./pi;       %Ângulo da corrente em graus.
            
            %Cálculo da corrente no gerador E1
            Xd1 = Xd*1i;                       %Xd complexo em Cart
            XLtgeracaoNew1 = XLtgeracaoNew*1i;
            Ecart = Vt1 + (XLtgeracaoNew1+Xd1)*I3;              %Tensao do gerador em Cart
            global Emodule;                    %Tornando Emodule global
            Emodule = abs(Ecart);              %Módulo da tensão do gerador em polar
            global deltazero;                  %Tornando Eangle global
            deltazero = angle(Ecart)*180./pi;  %ângulo da tensao do gerador em graus
            
            %Equação potência ângulo
            global PeRP                        %Declarando PeRP global
            PeRP = (Emodule*VA)./(XtaRP+Xd+XLtgeracaoNew);   %Equação potência-ângulo
            
            % Resultados de Saída
            PeRP = round(PeRP,3,'significant');         
            alpha = round(alpha,3,'significant');   
            Emodule = round(Emodule,3,'significant');         
            deltazero = round(deltazero,3,'significant');         
            app.RESULTADOS1.Value = ['Pe(Regime Permanente)    = ',num2str(PeRP),'sen(δ) pu'];
            app.RESULTADOS2.Value = ['Vt(Tensão Barra gerador) = ',num2str(VT),' < ', num2str(alpha),'º pu'];
            app.RESULTADOS3.Value = ['E1(Tensão no Gerador)    = ',num2str(Emodule),' < ',num2str(deltazero),'º pu'];
            
            %         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%FUNÇÃO RECEBER MATRIZ%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Atribuição de Variávies:
            Tamanho_matriz = 3;
            BarraELIMINADA = 3;
            % Equações e lógica
            %CONVERSOR DE IMPEDÂNCIA PARA ADMITÂNCIA
            MatrizZERO = zeros(Tamanho_matriz);
            for i = 1:Tamanho_matriz;
                for j = 1:Tamanho_matriz;
                    if Zbarra(i,j) ~= 0;
                        MatrizZERO(i,j) = -1/Zbarra(i,j);
                    else
                        Zbarra(i,j)= 0;
                    end
                end
            end
            %CALCULO DOS VALORES DA DIAGONAL PRINCIPAL
            for i = 1:Tamanho_matriz;
                for j = 1:Tamanho_matriz;
                    if i == j;
                        MatrizZERO(i,j) = sum(MatrizZERO(i,:));
                        MatrizZERO(i,j) = -1*MatrizZERO(i,j);
                    end
                end
            end
            Ybarra = MatrizZERO;
            %REDUÇÃO DE KRON
            Tamanho_matriz_novo = Tamanho_matriz - 1;
            if BarraELIMINADA ~= Tamanho_matriz;
                MatrizZERO(:,BarraELIMINADA); %pega o valor da coluna que sera eliminada
                MatrizZERO(:,end);            %pega o valor da ultima coluna da matriz
                MatrizSUP1 = MatrizZERO;
                
                MatrizSUP1(:,end) = MatrizZERO(:,BarraELIMINADA); %aplica o valor da coluna eliminada na ultima coluna da matriz
                MatrizSUP1(:,BarraELIMINADA) = MatrizZERO(:,end); % aplica o valor da ultima coluna  no lugar da coluna eliminada
                
                MatrizSUP1(BarraELIMINADA,:); % pega o valor da linha eliminada
                MatrizSUP1(end,:)    ;        % pega o valor da ultima linha da matriz
                
                MatrizSUP2=MatrizSUP1;
                
                MatrizSUP2(end,:) = MatrizSUP1(BarraELIMINADA,:); %aplica o valor da linha eliminada na ultima linha da matriz
                MatrizSUP2(BarraELIMINADA,:) = MatrizSUP1(end,:); %aplica o valor da linha coluna  no lugar da linha eliminada
                
                MatrizFINAL = zeros(Tamanho_matriz_novo);
                for i = 1:Tamanho_matriz_novo
                    for j = 1:Tamanho_matriz_novo
                        MatrizFINAL(i,j) = MatrizSUP2(i,j) - (MatrizSUP2(i,end)*MatrizSUP2(end,j)/MatrizSUP2(end,end));
                    end
                end
            else
                MatrizFINAL = zeros(Tamanho_matriz_novo);
                for i = 1:Tamanho_matriz_novo
                    for j = 1:Tamanho_matriz_novo
                        MatrizFINAL(i,j) = MatrizZERO(i,j) - (MatrizZERO(i,end)*MatrizZERO(end,j)/MatrizZERO(end,end));
                    end
                end
            end
            
            
            %       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%DURANTE FALTA%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            VA = 1;
            x12 = -1./MatrizFINAL(1,2);
            PeDF = (Emodule*VA)./x12*1i;
            PeDF = round(PeDF,3,'significant');
            app.RESULTADOS4.Value  = ['Pe(Durante_falta)                 =  ',num2str(PeDF),'sen(δ) pu'];
            %      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%POS FALTA%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            PePF = (Emodule*VA)/(XeaPF);     %Equação potência-ângulo
            PePF = round(PePF,3,'significant');
            app.RESULTADOS6.Value  = ['Pe(Pós Falta)                         =  ',num2str(PePF),'sen(δ) pu'];
            
            %     %A4%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            deltazeroRAD = deltazero*pi/180;
            a = sin(deltazeroRAD);
            b = (pi-2*deltazeroRAD);
            c = cos(deltazeroRAD);
            d = (a*b-c);
            deltacriticoRAD = acos(d);
            deltac = deltacriticoRAD*180./pi;
            app.RESULTADOS5.Value  = ['δ Crítico(PE = zero)   = ',num2str(deltac),'º'];
            %    %%A5%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            fe = app.fe1.Value;
            Pmec = app.Pmec1.Value;
            H = app.H1.Value;
            H = H*Sgeracao*numgeradores/SpuSist;
            EangleRAD = deltazero*pi/180;
            a = (deltacriticoRAD - EangleRAD) *4*H;
            b = 2*pi*fe*Pmec/numgeradores;
            Tcritico = sqrt(a/b);
            T=1/60;
            ciclos = Tcritico/T;
            Tcritico = round(Tcritico,3,'significant');
            ciclos = round(ciclos,3,'significant'); 
            app.RESULTADOS7.Value  = ['t Crítico(PE = zero) = ',num2str(Tcritico),' seg ou ', num2str(ciclos),' ciclos'];
            %A7%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %A7%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Pmec2 = Pmec * Pomec;
            omega = round(2*pi*fe*Pmec,4,'significant');
            app.OS1.Value = [num2str(2*H),'/',num2str(omega),'*(d²δ/dt²) = ',num2str(Pmec2) ,' - ',num2str(PeRP),'*sen(δ)'];
            app.OS2.Value = [num2str(2*H),'/',num2str(omega),'*(d²δ/dt²) = ',num2str(Pmec2) ,' - ',num2str(PeDF),'*sen(δ)'];
            app.OS3.Value = [num2str(2*H),'/',num2str(omega),'*(d²δ/dt²) = ',num2str(Pmec2) ,' - ',num2str(PePF),'*sen(δ)'];
            
            
            %%%%%%%%%%
            
            
            %      %DELTA MAXIMO%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            r1 = PeDF/PeRP;
            r2 = PePF/PeRP;
            delta1 = asin(Pmec*Pomec/PePF);
            deltamaxRAD = (pi - delta1);
            deltamax = deltamaxRAD*180/pi;
            deltamax = round(deltamax,4,'significant');
            app.RESULTADOS8.Value = ['δ Máximo(PE dif zero)  = ',num2str(deltamax),'º'];
            %     %ANGULO CRITICO LIMPEZA DE FALTA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            deltazeroRAD = deltazero*pi/180;
            a = (Pmec*Pomec/PeRP)*(deltamaxRAD - deltazeroRAD) + r2*cos(deltamaxRAD) -r1*cos(deltazeroRAD);
            b = r2-r1;
            
            deltacriticoPdif0RAD = acos(a/b);
            deltacriticoPdif0 = deltacriticoPdif0RAD*180./pi;
            deltacriticoPdif0 = round(deltacriticoPdif0,3,'significant');
            app.RESULTADOS9.Value = ['δ Crítico(PE dif zero) = ',num2str(deltacriticoPdif0),'º'];
            
            %     %plotagem%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Cálculo para Plot da Equação potência ângulo RP
            
            AngulosPeRP_RAD = (0:180)*pi/180;
            PotenciasPeRP_eixoY = PeRP*sin(AngulosPeRP_RAD);
            AngulosPeRP_GRAU = AngulosPeRP_RAD*180/pi;
            
            
            %Cálculo para Plot da Equação potência ângulo DF
            AngulosPeDF_RAD = (0:180)*pi/180;
            PotenciasPeDF_eixoY = PeDF*sin(AngulosPeDF_RAD);
            AngulosPeDF_GRAU = AngulosPeDF_RAD*180/pi;
            
            
            %Cálculo para Plot da Equação potência ângulo PF
            AngulosPePF_RAD = (0:180)*pi/180;
            PotenciasPePF_eixoY = PePF*sin(AngulosPePF_RAD);
            AngulosPePF_GRAU = AngulosPePF_RAD*180/pi;
            
            
            %Converter angulos para uma casa decimal
            deltazeroSIGNIFICANT = round(deltazero,2,'significant');
            deltacriticoPdif0SIGNIFICANT = round(deltacriticoPdif0,2,'significant');
            deltamaxSIGNIFICANT = round(deltamax,3,'significant');
            
            graphpmec= Pmec*Pomec;
            plot(app.UIAxes,AngulosPeRP_GRAU,PotenciasPeRP_eixoY,'--k','LineWidth',1.0);
            plot(app.UIAxes,AngulosPeDF_GRAU,PotenciasPeDF_eixoY,':k','LineWidth',1.0);
            plot(app.UIAxes,AngulosPePF_GRAU,PotenciasPePF_eixoY,'k','LineWidth',1.0);
            set(app.UIAxes,'XTick',[0 deltazeroSIGNIFICANT deltacriticoPdif0SIGNIFICANT deltamaxSIGNIFICANT 180])
            set(app.UIAxes,'YTick', [0  PeDF graphpmec PePF PeRP])
            
            Filename='Matrizreatancia.xlsx';
            [~,~, Raw]=xlsread(Filename,1);
            [Raw{:, :}]=deal(NaN);
            xlswrite(Filename, Raw, 1,'A5:C7');
            YbarraExcel = num2cell(Zbarra);
            YbarraExcel = cellfun(@num2str , YbarraExcel, 'UniformOutput', false);
            xlswrite('Matrizreatancia.xlsx',YbarraExcel',1,'A5:C7')
            
            xlswrite(Filename, Raw, 1,'E5:G7');
            YbarraExcel = num2cell(Ybarra);
            YbarraExcel = cellfun(@num2str , YbarraExcel, 'UniformOutput', false);
            xlswrite('Matrizreatancia.xlsx',YbarraExcel',1,'E5:G7')
            
            xlswrite(Filename, Raw, 1,'A15:B16');
            YbarraExcel = num2cell(MatrizFINAL);
            YbarraExcel = cellfun(@num2str , YbarraExcel, 'UniformOutput', false);
            xlswrite('Matrizreatancia.xlsx',YbarraExcel',1,'A15:B16')
            
            tempo2 = toc;
            app.Tempoexec2.Value = tempo2;
            
        end

        % Button11 button pushed function
        function matrizesta(app)
            
        end

        % Button2 button pushed function
        function LimparEstab(app)
            app.Vprimario1.Value = 0;
            app.Vsecundario1.Value =0;
            app.Stransformador1.Value = 0;
            app.impedanciatrafo1.Value = 0;
            app.queda1.Value = 0;
            app.SpuSist1.Value =  0;
            app.Vgeracao1.Value = 0;
            app.Xdgeracao1.Value =0;
            app.Sgeracao1.Value =0;
            app.VLtgeracao1.Value=0;
            app.XLtgeracao1.Value =0;
            app.SLtgeracao1.Value =0;
            app.VLt11.Value = 0;
            app.XLt11.Value =0;
            app.SLt11.Value = 0;
            app.XLt21.Value =0;
            app.LTD1.Value = 0;
            app.VT1.Value = 0;
            app.VA1.Value = 0;
            app.Pe1.Value = 0;
            app.angleVA1.Value =0;
            app.fe1.Value = 0;
            app.Pmec1.Value =0;
            app.H1.Value = 0;
            cla(app.UIAxes);
            app.OS1.Value = '';
            app.OS2.Value = '';
            app.OS3.Value = '';
            app.RESULTADOS1.Value = '';
            app.RESULTADOS2.Value = '';
            app.RESULTADOS3.Value = '';
            app.RESULTADOS4.Value = '';
            app.RESULTADOS5.Value = '';
            app.RESULTADOS6.Value = '';
            app.RESULTADOS7.Value = '';
            app.RESULTADOS8.Value = '';
            app.RESULTADOS9.Value = '';
            
        end

        % Button11 button pushed function
        function teste2erstab(app)
            app.Vprimario1.Value = 1;
            app.Vsecundario1.Value =1;
            app.Stransformador1.Value = 1;
            app.impedanciatrafo1.Value = 0.1;
            app.queda1.Value = 50;
            app.SpuSist1.Value =  1;
            app.Vgeracao1.Value = 1;
            app.Xdgeracao1.Value =0.2;
            app.Sgeracao1.Value =1;
            app.VLtgeracao1.Value=1;
            app.XLtgeracao1.Value =0;
            app.SLtgeracao1.Value =1;
            app.VLt11.Value = 1;
            app.XLt11.Value =0.4;
            app.SLt11.Value = 1;
            app.XLt21.Value =0.4;
            app.LTD1.Value = 1;
            app.VT1.Value = 1;
            app.VA1.Value = 1;
            app.Pe1.Value = 1;
            app.angleVA1.Value =0;
            app.fe1.Value = 60;
            app.Pmec1.Value =1;
            app.H1.Value = 5;
        end

        % Button12 button pushed function
        function matrizesEstab(app)
            winopen('Matrizreatancia.xlsx')
            
        end

        % Button13 button pushed function
        function LINHARES(app)
            app.Vprimario1.Value = 13.8;
            app.Vsecundario1.Value =138;
            app.Stransformador1.Value = 82;
            app.impedanciatrafo1.Value = 13;
            app.numerotrafos1.Value = 2;
            app.queda1.Value = 50;
            app.SpuSist1.Value =  10.549;
            app.Vgeracao1.Value = 13.8;
            app.Xdgeracao1.Value =32.6;
            app.Sgeracao1.Value =10.549;
            app.VLtgeracao1.Value=0;
            app.XLtgeracao1.Value =0;
            app.SLtgeracao1.Value =0;
            app.VLt11.Value = 138;
            app.XLt11.Value =13.48;
            app.SLt11.Value = 100;
            app.XLt21.Value =13.48;
            app.LTD1.Value = 2;
            app.VT1.Value = 1;
            app.VA1.Value = 1;
            app.Pe1.Value = 24;
            app.angleVA1.Value =0;
            app.fe1.Value = 60;
            app.Pmec1.Value = 24;
            app.H1.Value = 5;
            app.numgeradores1.Value = 24;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Ferramenta para Análise de Sistemas Elétricos de Potência';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Estabilidade';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @CalcularEstabilidade);
            app.Button.Text = 'Calcular';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LimparEstab);
            app.Button2.Text = 'Limpar';
            app.Button3 = uibutton(app.Tab, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Testehum);
            app.Button3.Text = 'LINHARES 01';
            app.Gerador = uipanel(app.Tab);
            app.Gerador.Title = 'Dados Gerador';
            app.Gerador.Units = 'pixels';
            app.Label6 = uilabel(app.Gerador);
            app.Label6.Text = 'Constante de Máquina  [ H ]';
            app.H1 = uieditfield(app.Gerador, 'numeric');
            app.H1.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField10 = uilabel(app.Gerador);
            app.LabelNumericEditField10.Text = 'Tensão do Gerador [ kV ]';
            app.Vgeracao1 = uieditfield(app.Gerador, 'numeric');
            app.Vgeracao1.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField11 = uilabel(app.Gerador);
            app.LabelNumericEditField11.Text = 'Potência Base do Gerador [ MVA ]';
            app.Sgeracao1 = uieditfield(app.Gerador, 'numeric');
            app.Sgeracao1.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField12 = uilabel(app.Gerador);
            app.LabelNumericEditField12.Text = 'Reatância Transitória Gerador [ % ]';
            app.Xdgeracao1 = uieditfield(app.Gerador, 'numeric');
            app.Xdgeracao1.ValueDisplayFormat = '%11.10g';
            app.Label27 = uilabel(app.Gerador);
            app.Label27.Text = 'Número de Geradores ';
            app.numgeradores1 = uieditfield(app.Gerador, 'numeric');
            app.numgeradores1.ValueDisplayFormat = '%11.10g';
            app.LinhaGeracao = uipanel(app.Tab);
            app.LinhaGeracao.Title = 'Dados Linha de Geração';
            app.LinhaGeracao.Units = 'pixels';
            app.LabelNumericEditField13 = uilabel(app.LinhaGeracao);
            app.LabelNumericEditField13.Text = 'Tensão Linha Geração [ kV]';
            app.VLtgeracao1 = uieditfield(app.LinhaGeracao, 'numeric');
            app.LabelNumericEditField14 = uilabel(app.LinhaGeracao);
            app.LabelNumericEditField14.Text = 'Potência Base Linha Geração [ MVA]';
            app.SLtgeracao1 = uieditfield(app.LinhaGeracao, 'numeric');
            app.LabelNumericEditField15 = uilabel(app.LinhaGeracao);
            app.LabelNumericEditField15.Text = 'Reatância Linha Geração [ % ]';
            app.XLtgeracao1 = uieditfield(app.LinhaGeracao, 'numeric');
            app.Linhadetransmissao = uipanel(app.Tab);
            app.Linhadetransmissao.Title = 'Linhas de Transmissão';
            app.Linhadetransmissao.Units = 'pixels';
            app.LabelNumericEditField4 = uilabel(app.Linhadetransmissao);
            app.LabelNumericEditField4.Text = 'Potência Base das LT''s [ MVA ]';
            app.SLt11 = uieditfield(app.Linhadetransmissao, 'numeric');
            app.LabelNumericEditField16 = uilabel(app.Linhadetransmissao);
            app.LabelNumericEditField16.Text = 'Reatância LT1 [ % ]';
            app.XLt11 = uieditfield(app.Linhadetransmissao, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.Linhadetransmissao);
            app.LabelNumericEditField6.Text = 'Reatância LT2 [ % ]';
            app.XLt21 = uieditfield(app.Linhadetransmissao, 'numeric');
            app.LabelNumericEditField17 = uilabel(app.Linhadetransmissao);
            app.LabelNumericEditField17.Text = 'Linha Eliminada [ 1 ou 2 ]';
            app.LTD1 = uieditfield(app.Linhadetransmissao, 'numeric');
            app.LabelNumericEditField18 = uilabel(app.Linhadetransmissao);
            app.LabelNumericEditField18.Text = 'Tensão das LT''s [ kV ]';
            app.VLt11 = uieditfield(app.Linhadetransmissao, 'numeric');
            app.Label23 = uilabel(app.Linhadetransmissao);
            app.Label23.Text = 'Porcentagem da Falta na  LT [ % ]';
            app.queda1 = uieditfield(app.Linhadetransmissao, 'numeric');
            app.Parametrosbarras = uipanel(app.Tab);
            app.Parametrosbarras.Title = 'Parâmetros das Barras do Sistema';
            app.Parametrosbarras.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Parametrosbarras);
            app.LabelNumericEditField.Text = 'Tensão Barra de Geração [ p.u ]';
            app.VT1 = uieditfield(app.Parametrosbarras, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.Parametrosbarras);
            app.LabelNumericEditField2.Text = 'Tensão Barramento Infinito [ p.u ]';
            app.VA1 = uieditfield(app.Parametrosbarras, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.Parametrosbarras);
            app.LabelNumericEditField5.Text = 'Ângulo Barramento Infinito [ graus]';
            app.angleVA1 = uieditfield(app.Parametrosbarras, 'numeric');
            app.ParametrosSist = uipanel(app.Tab);
            app.ParametrosSist.Title = 'Parâmetros do Sistema ';
            app.ParametrosSist.Units = 'pixels';
            app.LabelNumericEditField3 = uilabel(app.ParametrosSist);
            app.LabelNumericEditField3.Text = 'Potência Elétrica [ p.u ]';
            app.Pe1 = uieditfield(app.ParametrosSist, 'numeric');
            app.Label4 = uilabel(app.ParametrosSist);
            app.Label4.Text = 'Potência Mecânica [ p.u ]';
            app.Pmec1 = uieditfield(app.ParametrosSist, 'numeric');
            app.Label5 = uilabel(app.ParametrosSist);
            app.Label5.Text = 'Frequência Elétrica [ Hz ]';
            app.fe1 = uieditfield(app.ParametrosSist, 'numeric');
            app.Label17 = uilabel(app.Tab);
            app.Label17.Text = 'Estabilidade de Sistemas de Potência';
            app.Panel2 = uipanel(app.Tab);
            app.Panel2.Title = 'Dados do Transformador';
            app.Panel2.Units = 'pixels';
            app.Label19 = uilabel(app.Panel2);
            app.Label19.Text = 'Impedância do Transformador  [ % ]';
            app.impedanciatrafo1 = uieditfield(app.Panel2, 'numeric');
            app.Label20 = uilabel(app.Panel2);
            app.Label20.Text = 'Tensão no Primário [ kV ]';
            app.Vprimario1 = uieditfield(app.Panel2, 'numeric');
            app.Label21 = uilabel(app.Panel2);
            app.Label21.Text = 'Potência Base do Trafo [ MVA ]';
            app.Stransformador1 = uieditfield(app.Panel2, 'numeric');
            app.Label22 = uilabel(app.Panel2);
            app.Label22.Text = 'Tensão no Secundário [ kV ]';
            app.Vsecundario1 = uieditfield(app.Panel2, 'numeric');
            app.Label28 = uilabel(app.Panel2);
            app.Label28.Text = 'Número de Transformadores ';
            app.numerotrafos1 = uieditfield(app.Panel2, 'numeric');
            app.BasesSitema = uipanel(app.Tab);
            app.BasesSitema.Title = 'Bases do Sistema';
            app.BasesSitema.Units = 'pixels';
            app.LabelNumericEditField9 = uilabel(app.BasesSitema);
            app.LabelNumericEditField9.Text = 'Potência Base do Sistema [ MVA ]';
            app.SpuSist1 = uieditfield(app.BasesSitema, 'numeric');
            app.SpuSist1.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField19 = uilabel(app.Tab);
            app.LabelNumericEditField19.Text = 'Tempo de execução: ';
            app.Tempoexec2 = uieditfield(app.Tab, 'numeric');
            app.Tempoexec2.ValueDisplayFormat = '%11.2g';
            app.Tempoexec2.Editable = 'off';
            app.Label26 = uilabel(app.Tab);
            app.Label26.Text = 'segundos';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Fluxo de Potência';
            app.Label10 = uilabel(app.Tab2);
            app.Label10.Text = 'Inserir parâmetros das Barras: ';
            app.Label11 = uilabel(app.Tab2);
            app.Label11.Text = 'Inserir dados das Linhas de Transmissão:';
            app.Label12 = uilabel(app.Tab2);
            app.Label12.Text = 'Resultados:';
            app.Label13 = uilabel(app.Tab2);
            app.Label13.Text = 'Ferramenta para cálculo de Fluxo de Potência';
            app.Label15 = uilabel(app.Tab2);
            app.Label15.Text = 'segundos';
            app.Barras2 = uibutton(app.Tab2, 'push');
            app.Barras2.ButtonPushedFcn = createCallbackFcn(app, @BotaoBarras);
            app.Barras2.Text = 'Parâmetros das Barras';
            app.Button9 = uibutton(app.Tab2, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @BotaoLinhas);
            app.Button9.Text = 'Dados das LTs';
            app.Button10 = uibutton(app.Tab2, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Calcular);
            app.Button10.Text = 'Calcular';
            app.LabelNumericEditField20 = uilabel(app.Tab2);
            app.LabelNumericEditField20.Text = 'Tempo de execução';
            app.Tempoexec = uieditfield(app.Tab2, 'numeric');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Resultados Estabilidade ';
            app.EQpotenciaangulo = uipanel(app.Tab3);
            app.EQpotenciaangulo.Title = 'Equações Potência Ângulo: ';
            app.EQpotenciaangulo.Units = 'pixels';
            app.RESULTADOS1 = uieditfield(app.EQpotenciaangulo, 'text');
            app.RESULTADOS1.Editable = 'off';
            app.RESULTADOS4 = uieditfield(app.EQpotenciaangulo, 'text');
            app.RESULTADOS4.Editable = 'off';
            app.RESULTADOS6 = uieditfield(app.EQpotenciaangulo, 'text');
            app.RESULTADOS6.Editable = 'off';
            app.TensoesComplexas = uipanel(app.Tab3);
            app.TensoesComplexas.Title = 'Tensões Complexas: ';
            app.TensoesComplexas.Units = 'pixels';
            app.RESULTADOS2 = uieditfield(app.TensoesComplexas, 'text');
            app.RESULTADOS2.Editable = 'off';
            app.RESULTADOS3 = uieditfield(app.TensoesComplexas, 'text');
            app.RESULTADOS3.Editable = 'off';
            app.PeZero = uipanel(app.Tab3);
            app.PeZero.Title = 'Ângulo e tempo críticos para Pe transmitida nula';
            app.PeZero.Units = 'pixels';
            app.RESULTADOS5 = uieditfield(app.PeZero, 'text');
            app.RESULTADOS5.Editable = 'off';
            app.RESULTADOS7 = uieditfield(app.PeZero, 'text');
            app.RESULTADOS7.Editable = 'off';
            app.EqOscilacao = uipanel(app.Tab3);
            app.EqOscilacao.Title = 'Equações de Oscilação';
            app.EqOscilacao.Units = 'pixels';
            app.OS1 = uieditfield(app.EqOscilacao, 'text');
            app.OS1.Editable = 'off';
            app.OS2 = uieditfield(app.EqOscilacao, 'text');
            app.OS2.Editable = 'off';
            app.OS3 = uieditfield(app.EqOscilacao, 'text');
            app.OS3.Editable = 'off';
            app.PEdifZero = uipanel(app.Tab3);
            app.PEdifZero.Title = 'Ângulos para Pe transmitida Diferente de zero';
            app.PEdifZero.Units = 'pixels';
            app.RESULTADOS9 = uieditfield(app.PEdifZero, 'text');
            app.RESULTADOS9.Editable = 'off';
            app.RESULTADOS8 = uieditfield(app.PEdifZero, 'text');
            app.RESULTADOS8.Editable = 'off';
            app.Panel = uipanel(app.Tab3);
            app.Panel.Units = 'pixels';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Potência Elétrica versus Ângulo');
            xlabel(app.UIAxes, 'δ[graus]');
            ylabel(app.UIAxes, 'Potência Elétrica [pu]');
            app.UIAxes.LineStyleOrder = {'- '};
            app.Label24 = uilabel(app.Tab3);
            app.Label24.Text = 'Resultados - Estudo de Estabilidade Transitória';
            app.Button12 = uibutton(app.Tab3, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @matrizesEstab);
            app.Button12.Text = 'Abrir';
            app.Label25 = uilabel(app.Tab3);
            app.Label25.Text = 'Exibir Matrizes do Sistema';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FinalASEPTCC6()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end